classdef AppMockUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        PracticeTrackerTab            matlab.ui.container.Tab
        PracticeNotesTextAreaLabel    matlab.ui.control.Label
        PracticeNotesTextArea         matlab.ui.control.TextArea
        SongsPanel                    matlab.ui.container.Panel
        SongDatabaseButton            matlab.ui.control.Button
        SongPieceDropDownLabel        matlab.ui.control.Label
        SongPieceDropDown             matlab.ui.control.DropDown
        SongPieceDropDown_2Label      matlab.ui.control.Label
        SongPieceDropDown_2           matlab.ui.control.DropDown
        SongPieceDropDown_3Label      matlab.ui.control.Label
        SongPieceDropDown_3           matlab.ui.control.DropDown
        MinutesEditFieldLabel         matlab.ui.control.Label
        MinutesEditField              matlab.ui.control.NumericEditField
        HoursEditFieldLabel           matlab.ui.control.Label
        HoursEditField                matlab.ui.control.NumericEditField
        MinutesEditField_2Label       matlab.ui.control.Label
        MinutesEditField_2            matlab.ui.control.NumericEditField
        HoursEditField_2Label         matlab.ui.control.Label
        HoursEditField_2              matlab.ui.control.NumericEditField
        MinutesEditField_3Label       matlab.ui.control.Label
        MinutesEditField_3            matlab.ui.control.NumericEditField
        HoursEditField_3Label         matlab.ui.control.Label
        HoursEditField_3              matlab.ui.control.NumericEditField
        DateEditFieldLabel            matlab.ui.control.Label
        DateEditField                 matlab.ui.control.EditField
        TimeEditFieldLabel            matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.EditField
        WhatsyourgoalforthispracticesessionLabel  matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        InstrumentDropDownLabel       matlab.ui.control.Label
        InstrumentDropDown            matlab.ui.control.DropDown
        Circleof5thsTab               matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        GuitarChordsTab               matlab.ui.container.Tab
        KeyDropDownLabel              matlab.ui.control.Label
        GuitarChords_KeyDD            matlab.ui.control.DropDown
        GuitarChords_NeckAxes         matlab.ui.control.UIAxes
        ChordDropDownLabel            matlab.ui.control.Label
        GuitarChords_ChordDD          matlab.ui.control.DropDown
        CAGEDopenchordListBoxLabel    matlab.ui.control.Label
        GuitarChords_CAGEDLB          matlab.ui.control.ListBox
        GuitarChords_CO5Axes          matlab.ui.control.UIAxes
        GuitarChords_chordAxes        matlab.ui.control.UIAxes
        GuitarChords_DisplayChordBtn  matlab.ui.control.Button
        MetronomeTab                  matlab.ui.container.Tab
        SongDatabaseTab               matlab.ui.container.Tab
        AddSongButton                 matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        InstrumentsTab                matlab.ui.container.Tab
        AddInstrumentButton           matlab.ui.control.Button
        UITable2                      matlab.ui.control.Table
    end

    properties (Constant)
        %DIR = 'TIMBR_Dat';
        %IMG_F_TYP = '.png';
        
    end
    
    properties (Access = private)
        practiceLog % Description
        %fMajMin = 'Major';
        %shapePng = 'Open_A.png';
        %subDir = 'ChordShapes';
        %cofPng = 'A.png';
        %file path to specific chord shape
        %crdFP = fullfile('TIMBR_Dat', 'ChordShapes', 'Major', 'Open_A.png');
        %file path to specific circle of fifths
        %cofFP = fullfile('TIMBR_Dat','CircleOf5ths','A.png');

        
    end

    methods (Access = private)
    
        function imgXLoc = imgPlaceMath(app, chord, openShape)
            %DEFINE LENGTH OF A FRET ON IMAGE AS IMG_FRET_LENGTH
            IMG_FRET_LENGTH = 1;
            chordShapePlacementArr = [
               %C       %A      %G      %E      %D
	   	9,	 0,	 2,	 5,	 7;%A
	       10,	 1,	 3,	 6,	 8;%Bb
	       11,	 2,	 4,	 7,	 9;%B
	        0,	 3,	 5,	 8,	10;%C
		1,	 4,	 6,	 9,	11;%Db
		2,	 5,	 7,	10,	 0;%D
		3,	 6,	 8,	11,	 1;%Eb
		4,	 7,	 9,	 0,	 2;%E
		5,	 8,	10,	 1,	 3;%F
		6,	 9,	11,	 2,	 4;%Gb
		7,	10,	 0,	 3,	 5;%G
		8,	11,	 1,	 4,	 6;%Ab
	   ];
            osNum = app.openShapeToNum(openShape);
            cNum = app.chordToNum(chord);
            imgXLoc = IMG_FRET_LENGTH * chordShapePlacementArr(cNum, osNum);
        end
        
        function cNum = chordToNum(app, chord)
            switch(chord)
                case 'A' 
                    cNum = 1;
                case 'Bb' 
                    cNum = 2;
                case 'B' 
                    cNum = 3;
                case 'C' 
                    cNum = 4;
                case 'Db'
                    cNum = 5;
                case 'D'
                    cNum = 6;
                case 'Eb'
                    cNum = 7;
                case 'E'
                    cNum = 8;
                case 'F' 
                    cNum = 9;
                case 'Gb' 
                    cNum = 10;
                case 'G' 
                    cNum = 11;
                case 'Ab' 
                    cNum = 12;
                otherwise
                    %ERROR
            end
        end
        
        function osNum = openShapeToNum(app, openShape)
            switch(openShape)
                case 'C' 
                    osNum = 1;
                case 'A' 
                    osNum = 2;
                case 'G' 
                    osNum = 3;
                case 'E' 
                    osNum = 4;
                case 'D'
                    osNum = 5;
                otherwise
                    %ERROR
            end
        end
    
        function concatChords(app, value)
            circleOfFifthsArr = [
                          "I",	"ii",	"iii",	"IV",	"V",	"vi",	"vii";%header
            		  "A",	"Bm",	"Dbm",	"D",	"E",	"Gbm",	"Ab*";
            		  "Bb",	"Cm",	"Dm",	"Eb",	"F",	"Gm",	"A*";
            		  "B",	"Dbm",	"Ebm",	"E",	"Gb",	"Abm",	"Bb*";
            		  "C",	"Dm",	"Em",	"F",	"G",	"Am",	"B*";
            		  "Db",	"Ebm",	"Fm",	"Gb",	"Ab",	"Bbm",	"C*";
            		  "D",	"Em",	"Gbm",	"G",	"A",	"Bm",	"Db*";
            		  "Eb",	"Fm",	"Gm",	"Ab",	"Bb",	"Cm",	"D*";
            		  "E",	"Gbm",	"Abm",	"A",	"B",	"Dbm",	"Eb*";
            		  "F",	"Gm",	"Am",	"Bb",	"C",	"Dm",	"E*";
            		  "Gb",	"Abm",	"Bbm",	"B",	"Db",	"Ebm",	"F*";
            		  "G",	"Am",	"Bm",	"C",	"D",	"Em",	"Gb*";
            		  "Ab",	"Bbm",	"Cm",	"Db",	"Eb",	"Fm",	"G*"
          	     ];
            
            chords = circleOfFifthsArr(ismember(circleOfFifthsArr(:,1),value),:);
            for ii=numel(chords):-1:1
                newItems(ii) = strcat(circleOfFifthsArr(1,ii), " : ", chords(ii));
            end
            app.GuitarChords_ChordDD.Items = newItems;
        end
        
        function tonality = cofNumToTonality(app, cofNum)
            %Determine tonality based on the circle of fifths number      
            switch(cofNum)
                case 'I' 
                    tonality = 'Major';
                case 'ii' 
                    tonality = 'Minor';
                case 'iii' 
                    tonality = 'Minor';
                case 'IV' 
                    tonality = 'Major';
                case 'V'
                    tonality = 'Major';
                case 'vi'
                    tonality = 'Minor';
                case 'vii'
                    tonality = 'Diminished';
                otherwise
                    tonality = 'ERROR';
                    
            end
        end
        
        function result = dispGuitarChord(app)
            cagedShape = app.GuitarChords_CAGEDLB.Value;
            cTyp = 'Bar';
            result = 1; %THIS SHOULD BE USED FOR ERROR CHECKING
            
            %Must be parsed into chord and cofNum
            CDD = app.GuitarChords_ChordDD.Value; 
            
            %PARSE NUMERAL FROM CDD            -> Tonality
            cofNum = extractBefore(CDD, ' :');
            
            %PARSE NOTE FROM CDD               -> Chord Letter
            note = extractAfter(CDD,': ');
            note = note(1:1);
            
            if (cagedShape == note)
                %Chord to be displayed is at the bottom of the neck
                cTyp = 'Open';
            end
            
            %Determine Tonality
            fMajMin = cofNumToTonality(app, cofNum);
            disp(fMajMin);
                                  
            %concat image name
            shapePng = strcat(cTyp,'_',cagedShape,'.png');
            disp(shapePng);
            %compile file path
            crdFP = fullfile(userpath, 'TIMBR_Dat','ChordShapes', fMajMin, shapePng);
            disp(crdFP);
            
            %FIXME
            imgXLoc = app.imgPlaceMath(note, cagedShape);
            disp(imgXLoc);
            
            %I4 = imshow(co5FP, 'Parent', app.GuitarChords_chordAxes, ...
            %    'XData', [1 app.GuitarChords_CO5Axes.Position(3)], ...
            %    'YData', [1 app.GuitarChords_CO5Axes.Position(4)]);
            % Set limits of axes
            %app.GuitarChords_CO5Axes.XLim = [0 I3.XData(2)];
            %app.GuitarChords_CO5Axes.YLim = [0 I3.YData(2)];
        end
        
        function result = updateCO5PNG(app, key)
            result = 1; %Used for error checking
            
            switch(key)
                case 'A' 
                    cofPng = 'A.png';
                    %Concat A chords to Chord Drop Down
                case 'Bb' 
                    cofPng = 'Bb.png';
                    %Concat Bb chords to Chord Drop Down
                case 'B' 
                    cofPng = 'B.png';
                    %Concat B chords to Chord Drop Down
                case 'C' 
                    cofPng = 'C.png';
                    %Concat C chords to Chord Drop Down
                case 'Db'
                   cofPng = 'Db.png';
                    %Concat Db chords to Chord Drop Down
                case 'D'
                    cofPng = 'D.jpg';
                    %Concat D chords to Chord Drop Down
                case 'Eb'
                    cofPng = 'Eb.jpg';
                    %Concat Eb chords to Chord Drop Down
                case 'E'
                    cofPng = 'E.png';
                    %Concat E chords to Chord Drop Down
                case 'F' 
                    cofPng = 'F.png';
                    %Concat F chords to Chord Drop Down
                case 'Gb' 
                    cofPng = 'Gb.png';
                    %Concat Gb chords to Chord Drop Down
                case 'G' 
                    cofPng = 'G.png';
                    %Concat G chords to Chord Drop Down
                case 'Ab' 
                    cofPng = 'Ab.png';
                    %Concat Ab chords to Chord Drop Down
                otherwise
                    %ERROR
                    cofPng = 'ERR.png';
                    %Don't concat to chord drop down
            end
            
            %Update the Circle of 5ths that is displayed on Guitar Chords Tab
            co5FP = fullfile(userpath,'TIMBR_Dat','CircleOf5ths', cofPng);
            
            I3 = imshow(co5FP, 'Parent', app.GuitarChords_CO5Axes, ...
                'XData', [1 app.GuitarChords_CO5Axes.Position(3)], ...
                'YData', [1 app.GuitarChords_CO5Axes.Position(4)]);
            % Set limits of axes
            app.GuitarChords_CO5Axes.XLim = [0 I3.XData(2)];
            app.GuitarChords_CO5Axes.YLim = [0 I3.YData(2)];
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.concatChords(app.GuitarChords_KeyDD.Value);
            % Display image and stretch to fill axes
            I = imshow('circlefifths.jpg', 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            I2 = imshow('guitarNeck.png', 'Parent', app.GuitarChords_NeckAxes, ...
                'XData', [1 app.GuitarChords_NeckAxes.Position(3)], ...
                'YData', [1 app.GuitarChords_NeckAxes.Position(4)]);
            % Set limits of axes
            app.GuitarChords_NeckAxes.XLim = [0 I2.XData(2)];
            app.GuitarChords_NeckAxes.YLim = [0 I2.YData(2)];
            
            I3 = imshow('A.png', 'Parent', app.GuitarChords_CO5Axes, ...
                'XData', [1 app.GuitarChords_CO5Axes.Position(3)], ...
                'YData', [1 app.GuitarChords_CO5Axes.Position(4)]);
            % Set limits of axes
            app.GuitarChords_CO5Axes.XLim = [0 I3.XData(2)];
            app.GuitarChords_CO5Axes.YLim = [0 I3.YData(2)];
            
            crdFP = fullfile(userpath, 'TIMBR_Dat','ChordShapes', 'Major', 'Bar_C.png');
            
            %Determines X Location of the Guitar Chord on the Neck
            imgXLoc = app.imgPlaceMath('A', 'C');
            disp(imgXLoc);
            
            I4 = imshow(crdFP, 'Parent', app.GuitarChords_chordAxes, ...
                'XData', [1 app.GuitarChords_chordAxes.Position(3)], ...
                'YData', [1 app.GuitarChords_chordAxes.Position(4)]);
            % Set limits of axes
            app.GuitarChords_chordAxes.XLim = [0 I4.XData(2)];
            app.GuitarChords_chordAxes.YLim = [0 I4.YData(2)];
        end

        % Value changed function: GuitarChords_KeyDD
        function GuitarChords_KeyDD_ValueChanged(app, event)
            key = app.GuitarChords_KeyDD.Value;
                        
            %Function for updating Circle of 5ths Image
            result = updateCO5PNG(app, key);
            
            %Concat Key chords to Chord Drop Down
            app.concatChords(key);
        end

        % Value changed function: GuitarChords_ChordDD
        function GuitarChords_ChordDD_ValueChanged(app, event)

        end

        % Value changed function: GuitarChords_CAGEDLB
        function CAGEDListBox_ValueChanged(app, event)
                   
        end

        % Button pushed function: GuitarChords_DisplayChordBtn
        function GuitarChords_DisplayChordBtnButtonPushed(app, event)
            result = dispGuitarChord(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PracticeTrackerTab = uitab(app.TabGroup);
            app.PracticeTrackerTab.Title = 'Practice Tracker';
            app.PracticeNotesTextAreaLabel = uilabel(app.PracticeTrackerTab);
            app.PracticeNotesTextAreaLabel.Text = 'Practice Notes';
            app.PracticeNotesTextArea = uitextarea(app.PracticeTrackerTab);
            app.SongsPanel = uipanel(app.PracticeTrackerTab);
            app.SongsPanel.Title = 'Songs';
            app.SongDatabaseButton = uibutton(app.SongsPanel, 'push');
            app.SongDatabaseButton.Text = 'Song Database';
            app.SongPieceDropDownLabel = uilabel(app.SongsPanel);
            app.SongPieceDropDownLabel.Text = 'Song/Piece';
            app.SongPieceDropDown = uidropdown(app.SongsPanel);
            app.SongPieceDropDown_2Label = uilabel(app.SongsPanel);
            app.SongPieceDropDown_2Label.Text = 'Song/Piece';
            app.SongPieceDropDown_2 = uidropdown(app.SongsPanel);
            app.SongPieceDropDown_3Label = uilabel(app.SongsPanel);
            app.SongPieceDropDown_3Label.Text = 'Song/Piece';
            app.SongPieceDropDown_3 = uidropdown(app.SongsPanel);
            app.MinutesEditFieldLabel = uilabel(app.SongsPanel);
            app.MinutesEditFieldLabel.Text = 'Minutes';
            app.MinutesEditField = uieditfield(app.SongsPanel, 'numeric');
            app.HoursEditFieldLabel = uilabel(app.SongsPanel);
            app.HoursEditFieldLabel.Text = 'Hours';
            app.HoursEditField = uieditfield(app.SongsPanel, 'numeric');
            app.MinutesEditField_2Label = uilabel(app.SongsPanel);
            app.MinutesEditField_2Label.Text = 'Minutes';
            app.MinutesEditField_2 = uieditfield(app.SongsPanel, 'numeric');
            app.HoursEditField_2Label = uilabel(app.SongsPanel);
            app.HoursEditField_2Label.Text = 'Hours';
            app.HoursEditField_2 = uieditfield(app.SongsPanel, 'numeric');
            app.MinutesEditField_3Label = uilabel(app.SongsPanel);
            app.MinutesEditField_3Label.Text = 'Minutes';
            app.MinutesEditField_3 = uieditfield(app.SongsPanel, 'numeric');
            app.HoursEditField_3Label = uilabel(app.SongsPanel);
            app.HoursEditField_3Label.Text = 'Hours';
            app.HoursEditField_3 = uieditfield(app.SongsPanel, 'numeric');
            app.DateEditFieldLabel = uilabel(app.PracticeTrackerTab);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.PracticeTrackerTab, 'text');
            app.TimeEditFieldLabel = uilabel(app.PracticeTrackerTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.PracticeTrackerTab, 'text');
            app.WhatsyourgoalforthispracticesessionLabel = uilabel(app.PracticeTrackerTab);
            app.WhatsyourgoalforthispracticesessionLabel.Text = 'What''s your goal for this practice session? ';
            app.TextArea = uitextarea(app.PracticeTrackerTab);
            app.InstrumentDropDownLabel = uilabel(app.PracticeTrackerTab);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.PracticeTrackerTab);
            app.Circleof5thsTab = uitab(app.TabGroup);
            app.Circleof5thsTab.Title = 'Circle of 5ths';
            app.UIAxes = uiaxes(app.Circleof5thsTab);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.GuitarChordsTab = uitab(app.TabGroup);
            app.GuitarChordsTab.Title = 'Guitar Chords';
            app.KeyDropDownLabel = uilabel(app.GuitarChordsTab);
            app.KeyDropDownLabel.Text = 'Key';
            app.GuitarChords_KeyDD = uidropdown(app.GuitarChordsTab);
            app.GuitarChords_KeyDD.Items = {'A', 'Bb', 'B', 'C', 'Db', 'D', 'Eb', 'E', 'F', 'Gb', 'G', 'Ab'};
            app.GuitarChords_KeyDD.ValueChangedFcn = createCallbackFcn(app, @GuitarChords_KeyDD_ValueChanged, true);
            app.GuitarChords_KeyDD.Value = 'A';
            app.GuitarChords_NeckAxes = uiaxes(app.GuitarChordsTab);
            app.GuitarChords_NeckAxes.XTick = [];
            app.GuitarChords_NeckAxes.XTickLabel = '';
            app.GuitarChords_NeckAxes.YTick = [];
            app.GuitarChords_NeckAxes.YTickLabel = {'[ ]'};
            app.ChordDropDownLabel = uilabel(app.GuitarChordsTab);
            app.ChordDropDownLabel.Text = 'Chord';
            app.GuitarChords_ChordDD = uidropdown(app.GuitarChordsTab);
            app.GuitarChords_ChordDD.Items = {'I', 'ii', 'iii', 'IV', 'V', 'vi', 'vii'};
            app.GuitarChords_ChordDD.ValueChangedFcn = createCallbackFcn(app, @GuitarChords_ChordDD_ValueChanged, true);
            app.GuitarChords_ChordDD.Value = 'I';
            app.CAGEDopenchordListBoxLabel = uilabel(app.GuitarChordsTab);
            app.CAGEDopenchordListBoxLabel.Text = {'CAGED'; 'open  '; 'chord '};
            app.GuitarChords_CAGEDLB = uilistbox(app.GuitarChordsTab);
            app.GuitarChords_CAGEDLB.Items = {'C', 'A', 'G', 'E', 'D'};
            app.GuitarChords_CAGEDLB.ValueChangedFcn = createCallbackFcn(app, @CAGEDListBox_ValueChanged, true);
            app.GuitarChords_CAGEDLB.Value = 'C';
            app.GuitarChords_CO5Axes = uiaxes(app.GuitarChordsTab);
            title(app.GuitarChords_CO5Axes, 'Title')
            xlabel(app.GuitarChords_CO5Axes, 'X')
            ylabel(app.GuitarChords_CO5Axes, 'Y')
            app.GuitarChords_chordAxes = uiaxes(app.GuitarChordsTab);
            title(app.GuitarChords_chordAxes, 'Title')
            xlabel(app.GuitarChords_chordAxes, 'X')
            ylabel(app.GuitarChords_chordAxes, 'Y')
            app.GuitarChords_chordAxes.XTick = [];
            app.GuitarChords_chordAxes.XTickLabel = {'[ ]'};
            app.GuitarChords_chordAxes.YTick = [];
            app.GuitarChords_chordAxes.YTickLabel = {'['};
            app.GuitarChords_DisplayChordBtn = uibutton(app.GuitarChordsTab, 'push');
            app.GuitarChords_DisplayChordBtn.ButtonPushedFcn = createCallbackFcn(app, @GuitarChords_DisplayChordBtnButtonPushed, true);
            app.GuitarChords_DisplayChordBtn.Text = 'Display Chord';
            app.MetronomeTab = uitab(app.TabGroup);
            app.MetronomeTab.Title = 'Metronome';
            app.SongDatabaseTab = uitab(app.TabGroup);
            app.SongDatabaseTab.Title = 'Song Database';
            app.AddSongButton = uibutton(app.SongDatabaseTab, 'push');
            app.AddSongButton.Text = 'Add Song';
            app.UITable = uitable(app.SongDatabaseTab);
            app.UITable.ColumnName = {'Title'; 'Artist/Composer'; 'Status'; 'Hours Practiced'; 'Delete'};
            app.UITable.RowName = {};
            app.InstrumentsTab = uitab(app.TabGroup);
            app.InstrumentsTab.Title = 'Instruments';
            app.AddInstrumentButton = uibutton(app.InstrumentsTab, 'push');
            app.AddInstrumentButton.Text = 'Add Instrument';
            app.UITable2 = uitable(app.InstrumentsTab);
            app.UITable2.ColumnName = {'Instrument'; 'Date'; 'Hours Practiced'; 'Delete'};
            app.UITable2.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppMockUp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end