classdef timbr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        timbrApp                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        PracticeTrackerTab            matlab.ui.container.Tab
        PracticeNotesTextAreaLabel    matlab.ui.control.Label
        PracticeNotesTextArea         matlab.ui.control.TextArea
        DateEditFieldLabel            matlab.ui.control.Label
        DateEditField                 matlab.ui.control.EditField
        TimeEditFieldLabel            matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.EditField
        GoalLabel                     matlab.ui.control.Label
        GoalTextArea                  matlab.ui.control.TextArea
        InstrumentDropDownLabel       matlab.ui.control.Label
        InstrumentDropDown            matlab.ui.control.DropDown
        SubmitSessionButton           matlab.ui.control.Button
        AMPMDropDown                  matlab.ui.control.DropDown
        SongDatabaseButton            matlab.ui.control.Button
        SongPieceDropDownLabel        matlab.ui.control.Label
        SongDropDown                  matlab.ui.control.DropDown
        SongPieceDropDown_2Label      matlab.ui.control.Label
        SongDropDown_2                matlab.ui.control.DropDown
        SongPieceDropDown_3Label      matlab.ui.control.Label
        SongDropDown_3                matlab.ui.control.DropDown
        HoursEditFieldLabel           matlab.ui.control.Label
        HoursEditField                matlab.ui.control.NumericEditField
        MinutesEditFieldLabel         matlab.ui.control.Label
        MinutesEditField              matlab.ui.control.NumericEditField
        MinutesEditField_2Label       matlab.ui.control.Label
        MinutesEditField_2            matlab.ui.control.NumericEditField
        HoursEditField_2Label         matlab.ui.control.Label
        HoursEditField_2              matlab.ui.control.NumericEditField
        MinutesEditField_3Label       matlab.ui.control.Label
        MinutesEditField_3            matlab.ui.control.NumericEditField
        HoursEditField_3Label         matlab.ui.control.Label
        HoursEditField_3              matlab.ui.control.NumericEditField
        ClearInputsButton             matlab.ui.control.Button
        RecallSessionButton           matlab.ui.control.Button
        GeneratePracticeReportButton  matlab.ui.control.Button
        GuitarChordsTab               matlab.ui.container.Tab
        GuitarChords_CAGEDLB          matlab.ui.control.ListBox
        GuitarChords_CO5Axes          matlab.ui.control.UIAxes
        GuitarChords_DisplayChordBtn  matlab.ui.control.Button
        KeyDropDownLabel              matlab.ui.control.Label
        GuitarChords_KeyDD            matlab.ui.control.DropDown
        ChordDropDownLabel            matlab.ui.control.Label
        GuitarChords_ChordDD          matlab.ui.control.DropDown
        SongDatabaseTab               matlab.ui.container.Tab
        AddSongButton                 matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        DeleteSongButton              matlab.ui.control.Button
        SearchButton                  matlab.ui.control.Button
        InstrumentsTab                matlab.ui.container.Tab
        AddInstrumentButton           matlab.ui.control.Button
        UITable2                      matlab.ui.control.Table
    end

    
    properties (Access = public)
        practiceLog % Practice Tracker object for Practice Tracker tab management
        co5PNG
        chordShapePNG
        fMajMin
        shapePNG
        xLoc
        fig
    end
    
    methods (Access = public)
        
        function imgXLoc = imgPlaceMath(app, chord, openShape)
            %DEFINE LENGTH OF A FRET ON IMAGE AS IMG_FRET_LENGTH
            IMG_FRET_LENGTH = 1;
            chordShapePlacementArr = [
                %C       %A      %G      %E      %D
           	   	9,	 0,	 2,	 5,	 7;%A
        	       10,	 1,	 3,	 6,	 8;%Bb
        	       11,	 2,	 4,	 7,	 9;%B
                0,	 3,	 5,	 8,	10;%C
              		1,	 4,	 6,	 9,	11;%Db
              		2,	 5,	 7,	10,	 0;%D
              		3,	 6,	 8,	11,	 1;%Eb
              		4,	 7,	 9,	 0,	 2;%E
              		5,	 8,	10,	 1,	 3;%F
              		6,	 9,	11,	 2,	 4;%Gb
              		7,	10,	 0,	 3,	 5;%G
              		8,	11,	 1,	 4,	 6;%Ab
            	   ];
            osNum = app.openShapeToNum(openShape);
            cNum = app.chordToNum(chord);
            imgXLoc = IMG_FRET_LENGTH * chordShapePlacementArr(cNum, osNum);
        end


        function concatChords(app, value)
            circleOfFifthsArr = [
                "I",	"ii",	"iii",	"IV",	"V",	"vi",	"vii";%header
            		  "A",	"Bm",	"Dbm",	"D",	"E",	"Gbm",	"Ab*";
            		  "Bb",	"Cm",	"Dm",	"Eb",	"F",	"Gm",	"A*";
            		  "B",	"Dbm",	"Ebm",	"E",	"Gb",	"Abm",	"Bb*";
            		  "C",	"Dm",	"Em",	"F",	"G",	"Am",	"B*";
            		  "Db",	"Ebm",	"Fm",	"Gb",	"Ab",	"Bbm",	"C*";
            		  "D",	"Em",	"Gbm",	"G",	"A",	"Bm",	"Db*";
            		  "Eb",	"Fm",	"Gm",	"Ab",	"Bb",	"Cm",	"D*";
            		  "E",	"Gbm",	"Abm",	"A",	"B",	"Dbm",	"Eb*";
            		  "F",	"Gm",	"Am",	"Bb",	"C",	"Dm",	"E*";
            		  "Gb",	"Abm",	"Bbm",	"B",	"Db",	"Ebm",	"F*";
            		  "G",	"Am",	"Bm",	"C",	"D",	"Em",	"Gb*";
            		  "Ab",	"Bbm",	"Cm",	"Db",	"Eb",	"Fm",	"G*"
          	     ];
            
            chords = circleOfFifthsArr(ismember(circleOfFifthsArr(:,1),value),:);
            for ii=numel(chords):-1:1
                newItems(ii) = strcat(circleOfFifthsArr(1,ii), " : ", chords(ii));
            end
            app.GuitarChords_ChordDD.Items = newItems;
        end
        
        
        function result = dispGuitarChord(app)
            %Function Variables
            offset = 35;
            cagedShape = app.GuitarChords_CAGEDLB.Value;
            cTyp = 'Bar';
            txt = " Open";
            result = 1; %THIS SHOULD BE USED FOR ERROR CHECKING
            CDD = app.GuitarChords_ChordDD.Value; %Parse for chord and cofnum
            
            %PARSE NUMERAL FROM CDD            -> Tonality
            cofNum = extractBefore(CDD, ' :');
            %PARSE NOTE FROM CDD               -> Chord Letter
            note = extractAfter(CDD,': ');
            note = note(1:1);
            
            %Used for display and file searching
            if (cagedShape == note)
                %Chord to be displayed is at the bottom of the neck
                cTyp = 'Open';
            end
            
            %Determine Tonality
            fMajMin = app.cofNumToTonality(cofNum);
            
            %Concat Image Name
            shapePng = strcat(cTyp,'_',cagedShape,'.png');
            %Compile File Path
            crdFP = fullfile(pwd, 'ProjectCode','TIMBR_DAT','ChordShapes', fMajMin, shapePng);
            disp(crdFP);
            
            %Determine the fret location
            imgXLoc = app.imgPlaceMath(note, cagedShape);
            
            %Create a figure to display a guitar chord
            f = figure();
            %Modify title based on bar chord
            if(strcmp(cTyp, 'Bar'))
                txt = strcat(" Bar at fret ", num2str(imgXLoc));
            end
            
            %Concatenate Figure Name
            figName = strcat(note, " ", fMajMin, txt);
            set(f,'name', figName);
           	
        	   %Display the guitar neck and resize image
        	   neck = imread(fullfile(pwd,'ProjectCode','TIMBR_DAT','guitarNeck2.png'));
            neck = imresize(neck, 0.75);
            [y,x,~] = size(neck);
            
            % generate axes
            ax = axes('Units','pixels', 'Position',[0 0 x y], 'XTick',[], 'YTick',[], ...
             			'Nextplot','add', 'YDir','reverse');
            % Display the image in the axes
       	    image(neck, 'parent', ax);
            
            %Determine the alpha data for the guitar chord
            [im, ~, alpha] = imread(crdFP);
            %Determine the size of the guitar chord
            [~,x1,~] = size(neck);
            
            %Place the image on the axis and set its position
            I4 = imshow(im, 'Parent', ax);
            set(I4, 'AlphaData', alpha, 'XData', [-10 -10+x1]);
            set(f,'Position',[100 100 x y]);
        end
        
        function result = updateCO5PNG(app, key)
            result = 1; %Used for error checking
            
            switch(key)
                case 'A'
                    cofPng = 'A.png';
                    %Concat A chords to Chord Drop Down
                case 'Bb'
                    cofPng = 'Bb.png';
                    %Concat Bb chords to Chord Drop Down
                case 'B'
                    cofPng = 'B.png';
                    %Concat B chords to Chord Drop Down
                case 'C'
                    cofPng = 'C.png';
                    %Concat C chords to Chord Drop Down
                case 'Db'
                    cofPng = 'Db.png';
                    %Concat Db chords to Chord Drop Down
                case 'D'
                    cofPng = 'D.png';
                    %Concat D chords to Chord Drop Down
                case 'Eb'
                    cofPng = 'Eb.png';
                    %Concat Eb chords to Chord Drop Down
                case 'E'
                    cofPng = 'E.png';
                    %Concat E chords to Chord Drop Down
                case 'F'
                    cofPng = 'F.png';
                    %Concat F chords to Chord Drop Down
                case 'Gb'
                    cofPng = 'Gb.png';
                    %Concat Gb chords to Chord Drop Down
                case 'G'
                    cofPng = 'G.png';
                    %Concat G chords to Chord Drop Down
                case 'Ab'
                    cofPng = 'Ab.png';
                    %Concat Ab chords to Chord Drop Down
                otherwise
                    %ERROR
                    cofPng = 'ERR.png';
                    %Don't concat to chord drop down
            end
            
            %Update the Circle of 5ths that is displayed on Guitar Chords Tab
            co5FP = fullfile(pwd,'ProjectCode','TIMBR_Dat','CircleOf5ths', cofPng);
            disp(co5FP);
            I3 = imshow(co5FP, 'Parent', app.GuitarChords_CO5Axes, ...
                'XData', [1 app.GuitarChords_CO5Axes.Position(3)], ...
                'YData', [1 app.GuitarChords_CO5Axes.Position(4)]);
            % Set limits of axes
            app.GuitarChords_CO5Axes.XLim = [0 I3.XData(2)];
            app.GuitarChords_CO5Axes.YLim = [0 I3.YData(2)];
        end
        
        
        function addSongToDatabase(app, name, artist, url)
            curData = app.UITable.Data; % Get current data from table
            newData = [curData; [{name}, {artist}, {url}]]; % append row to the bottom
            app.UITable.Data = newData; % Add the data back to the table
            app.sortSongDatabase; % Resort the entries
            app.practiceLog.populateSongsFromDataBase;
        end
        
        function deleteSongInDatabase(app, name)
            answer = questdlg('Are you sure you want to delete this song?', 'Delete Song', ...
           	    'Yes','No','No');
            switch answer % Handle response
                case 'Yes'
                    disp([answer 'Song deleted'])
                    app.UITable.Data(ismember(app.UITable.Data(:,1),name),:) = []; % Search table for name and clear that row
                    app.sortSongDatabase; % Resort the entries
                case 'No'
                    disp([answer 'Song not deleted'])
            end
        end
        
        function sortSongDatabase(app)
            data = app.UITable.Data;    % Get current data from table
            sortedData = sortrows(data, 1); % Sort the data by the first column
            app.UITable.Data = sortedData; % Add the data back to the table
        end
        
        
        function AddInstrumentToDatabase(app, instrument)
            curData = app.UITable2.Data; % Get current data from table
            newData = [curData; [{instrument}, {0}]]; % append row to the bottom
            app.UITable2.Data = newData; % Add the data back to the table
            app.practiceLog.populateInstrumentsFromDataBase;
        end
    end
    
    methods (Static)
        
        function cNum = chordToNum(chord)
            switch(chord)
                case 'A'
                    cNum = 1;
                case 'Bb'
                    cNum = 2;
                case 'B'
                    cNum = 3;
                case 'C'
                    cNum = 4;
                case 'Db'
                    cNum = 5;
                case 'D'
                    cNum = 6;
                case 'Eb'
                    cNum = 7;
                case 'E'
                    cNum = 8;
                case 'F'
                    cNum = 9;
                case 'Gb'
                    cNum = 10;
                case 'G'
                    cNum = 11;
                case 'Ab'
                    cNum = 12;
                otherwise
                    warning("Problem with fcn ChordToNum");
            end
        end
        
        function osNum = openShapeToNum(openShape)
            switch(openShape)
                case 'C'
                    osNum = 1;
                case 'A'
                    osNum = 2;
                case 'G'
                    osNum = 3;
                case 'E'
                    osNum = 4;
                case 'D'
                    osNum = 5;
                otherwise
                    warning("Problem with osNum");
            end
        end
        
        function tonality = cofNumToTonality(cofNum)
            %Determine tonality based on the circle of fifths number
            switch(cofNum)
                case 'I'
                    tonality = 'Major';
                case 'ii'
                    tonality = 'Minor';
                case 'iii'
                    tonality = 'Minor';
                case 'IV'
                    tonality = 'Major';
                case 'V'
                    tonality = 'Major';
                case 'vi'
                    tonality = 'Minor';
                case 'vii'
                    tonality = 'Diminished';
                otherwise
                    tonality = 'ERROR';
                    
            end
        end
        
        function results = isValidDate(in)
            date = strsplit(in, '/');
            if ~(numel(date) == 3)
                results = false;
                return
            end
            
            month = str2double(date{1});
            day = str2double(date{2});
            year = str2double(date{3});
            
            if isnan(month) || isnan(day) || isnan(year)
                results = false;
                return
            end
            % test month for correct range
            if (month < 0) || (month > 12)
                results = false;
                % test day for correct range
            elseif (day < 0) || (day> 31)
                results = false;
                % test year for correct range
            elseif (year < 1000 || year > 3000)
                results = false;
                % If we reach here, date is correct format
            else
                results = true;
            end
        end
        
        function results = isValidTime(in)
            date = strsplit(in, ':');
            if ~(numel(date) == 2)
                results = false;
                return
            end
            
            hour = str2double(date{1});
            min = str2double(date{2});
            
            if isnan(hour) || isnan(min)
                results = false;
                return
            end
            % test hour for correct range
            if (hour < 0) || (hour > 12)
                results = false;
                % test min for correct range
            elseif (min < 0) || (min > 60)
                results = false;
            else
                results = true;
            end
        end
    end
    
    methods (Access = private)
        
        function dispInitImages(app)
            
            % Display Circle of fifths
            I = imshow([pwd,'\ProjectCode\TIMBR_DAT\circlefifths.jpg'], 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            % Display initial chord
            I3 = imshow([pwd,'\ProjectCode\TIMBR_DAT\A.png'], 'Parent', app.GuitarChords_CO5Axes, ...
                'XData', [1 app.GuitarChords_CO5Axes.Position(3)], ...
                'YData', [1 app.GuitarChords_CO5Axes.Position(4)]);
            % Set limits of axes
            app.GuitarChords_CO5Axes.XLim = [0 I3.XData(2)];
            app.GuitarChords_CO5Axes.YLim = [0 I3.YData(2)];
            set(app.GuitarChords_CO5Axes,'xticklabel',{[]},'yticklabel',{[]})
        end
        
        
        function results = getSongs(app)
            results = app.UITable.Data(:,1);
        end
        
        function results = getInstruments(app)
            results = app.UITable2.Data(:,1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.concatChords(app.GuitarChords_KeyDD.Value);
            app.practiceLog = PracticeTracker(app); % Creates the practiceTracker object
            app.practiceLog.setUserData; % sets user data for the practice tracker elements
            app.practiceLog.populateSongsFromDataBase;
            app.addSongToDatabase('Fur elise', 'Beethoven', 'bomb.com');
            app.addSongToDatabase('Shallow', 'Lady Gaga', 'bomb.com');
            app.addSongToDatabase('Old Devil Moon', 'Josh Groban', 'bomb.com');
            app.AddInstrumentToDatabase('Guitar');
            app.AddInstrumentToDatabase('Piano');
            app.AddInstrumentToDatabase('Drums');
            app.practiceLog.populateInstrumentsFromDataBase;
            app.dispInitImages;
            
        end

        % Button pushed function: SubmitSessionButton
        function SubmitSessionButtonPushed(app, event)
            if ~(app.isValidDate(app.DateEditField.Value) && app.isValidTime(app.TimeEditField.Value))
                uiconfirm(app.timbrApp,'Invalid Time or date, please enter date in format mm/dd/yyyy, and time in format hh:mm','Invalid Date Time',...
                    'Icon','warning');
                return
            else
                app.practiceLog.submitSession;
                uiconfirm(app.timbrApp,'Session Submitted','Session Submit',...
                    'Icon','success');
            end
        end

        % Value changed function: DateEditField
        function DateEditFieldValueChanged(app, event)
            value = app.DateEditField.Value;
            if app.isValidDate(value)
                return
            else
                uiconfirm(app.timbrApp,'Invalid Date, please enter date in format mm/dd/yyyy','Invalid Date',...
                    'Icon','warning');
            end
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
            if app.isValidTime(value)
                return
            else
                uiconfirm(app.timbrApp,'Invalid Time, please enter time in format hh:mm','Invalid Time',...
                    'Icon','warning');
            end
        end

        % Button pushed function: ClearInputsButton
        function ClearInputsButtonPushed(app, event)
            app.practiceLog.clearPracticeTracker;
        end

        % Button pushed function: RecallSessionButton
        function RecallSessionButtonPushed(app, event)
            [file,path] = uigetfile('*.txt',... % Opens a dialog that lets you select png files
                'Select a file');
            if(file)
            app.practiceLog.getPracticeData([path, file]);
            end
        end

        % Value changed function: GuitarChords_KeyDD
        function GuitarChords_KeyDDValueChanged(app, event)
            key = app.GuitarChords_KeyDD.Value;
            
            %Function for updating Circle of 5ths Image
            result = updateCO5PNG(app, key);
            
            %Concat Key chords to Chord Drop Down
            app.concatChords(key);
        end

        % Button pushed function: GuitarChords_DisplayChordBtn
        function GuitarChords_DisplayChordBtnButtonPushed(app, event)
            result = dispGuitarChord(app);
        end

        % Button pushed function: AddSongButton
        function AddSongButtonPushed(app, event)
            prompt = {'Enter song name','Enter song artist(s)','Enter URL to purchase sheet music'};
            dlgtitle = 'Add Song';
            dims = [1 35];
            definput = {'','',''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            app.addSongToDatabase(answer{1},answer{2},answer{3});
        end

        % Button pushed function: DeleteSongButton
        function DeleteSongButtonPushed(app, event)
            prompt = {'Enter song name'};
            dlgtitle = 'Delete Song';
            dims = [1 35];
            definput = {''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.deleteSongInDatabase(answer{1});
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            prompt = {'Enter song name','Enter song artist(s)'};
            dlgtitle = 'Search for a Song';
            dims = [1 35];
            definput = {'',''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
        end

        % Button pushed function: SongDatabaseButton
        function SongDatabaseButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SongDatabaseTab;
        end

        % Button pushed function: GeneratePracticeReportButton
        function GeneratePracticeReportButtonPushed(app, event)
            GenerateReport(app.getSongs, app.getInsruments);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.timbrApp = uifigure('Visible', 'off');
            app.timbrApp.Name = 'UI Figure';
            app.FileMenu = uimenu(app.timbrApp);
            app.FileMenu.Text = 'File';
            app.SettingsMenu = uimenu(app.timbrApp);
            app.SettingsMenu.Text = 'Settings';
            app.OptionsMenu = uimenu(app.timbrApp);
            app.OptionsMenu.Text = 'Options';
            app.TabGroup = uitabgroup(app.timbrApp);
            app.PracticeTrackerTab = uitab(app.TabGroup);
            app.PracticeTrackerTab.Title = 'Practice Tracker';
            app.PracticeNotesTextAreaLabel = uilabel(app.PracticeTrackerTab);
            app.PracticeNotesTextAreaLabel.Text = 'Practice Notes';
            app.PracticeNotesTextArea = uitextarea(app.PracticeTrackerTab);
            app.DateEditFieldLabel = uilabel(app.PracticeTrackerTab);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.PracticeTrackerTab, 'text');
            app.DateEditField.ValueChangedFcn = createCallbackFcn(app, @DateEditFieldValueChanged, true);
            app.TimeEditFieldLabel = uilabel(app.PracticeTrackerTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.PracticeTrackerTab, 'text');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.GoalLabel = uilabel(app.PracticeTrackerTab);
            app.GoalLabel.Text = 'What''s your goal for this practice session? ';
            app.GoalTextArea = uitextarea(app.PracticeTrackerTab);
            app.InstrumentDropDownLabel = uilabel(app.PracticeTrackerTab);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.PracticeTrackerTab);
            app.InstrumentDropDown.Items = {'Guitar', 'Piano', 'Vocals', 'Trombone'};
            app.InstrumentDropDown.Value = 'Guitar';
            app.SubmitSessionButton = uibutton(app.PracticeTrackerTab, 'push');
            app.SubmitSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitSessionButtonPushed, true);
            app.SubmitSessionButton.Text = 'Submit Session';
            app.AMPMDropDown = uidropdown(app.PracticeTrackerTab);
            app.AMPMDropDown.Items = {'AM', 'PM'};
            app.AMPMDropDown.Value = 'AM';
            app.SongDatabaseButton = uibutton(app.PracticeTrackerTab, 'push');
            app.SongDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SongDatabaseButtonPushed, true);
            app.SongDatabaseButton.Text = 'Song Database';
            app.SongPieceDropDownLabel = uilabel(app.PracticeTrackerTab);
            app.SongPieceDropDownLabel.Text = 'Song/Piece';
            app.SongDropDown = uidropdown(app.PracticeTrackerTab);
            app.SongPieceDropDown_2Label = uilabel(app.PracticeTrackerTab);
            app.SongPieceDropDown_2Label.Text = 'Song/Piece';
            app.SongDropDown_2 = uidropdown(app.PracticeTrackerTab);
            app.SongPieceDropDown_3Label = uilabel(app.PracticeTrackerTab);
            app.SongPieceDropDown_3Label.Text = 'Song/Piece';
            app.SongDropDown_3 = uidropdown(app.PracticeTrackerTab);
            app.HoursEditFieldLabel = uilabel(app.PracticeTrackerTab);
            app.HoursEditFieldLabel.Text = 'Hours';
            app.HoursEditField = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.MinutesEditFieldLabel = uilabel(app.PracticeTrackerTab);
            app.MinutesEditFieldLabel.Text = 'Minutes';
            app.MinutesEditField = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.MinutesEditField_2Label = uilabel(app.PracticeTrackerTab);
            app.MinutesEditField_2Label.Text = 'Minutes';
            app.MinutesEditField_2 = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.HoursEditField_2Label = uilabel(app.PracticeTrackerTab);
            app.HoursEditField_2Label.Text = 'Hours';
            app.HoursEditField_2 = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.MinutesEditField_3Label = uilabel(app.PracticeTrackerTab);
            app.MinutesEditField_3Label.Text = 'Minutes';
            app.MinutesEditField_3 = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.HoursEditField_3Label = uilabel(app.PracticeTrackerTab);
            app.HoursEditField_3Label.Text = 'Hours';
            app.HoursEditField_3 = uieditfield(app.PracticeTrackerTab, 'numeric');
            app.ClearInputsButton = uibutton(app.PracticeTrackerTab, 'push');
            app.ClearInputsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearInputsButtonPushed, true);
            app.ClearInputsButton.Text = 'Clear Inputs';
            app.RecallSessionButton = uibutton(app.PracticeTrackerTab, 'push');
            app.RecallSessionButton.ButtonPushedFcn = createCallbackFcn(app, @RecallSessionButtonPushed, true);
            app.RecallSessionButton.Text = 'Recall Session';
            app.GeneratePracticeReportButton = uibutton(app.PracticeTrackerTab, 'push');
            app.GeneratePracticeReportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePracticeReportButtonPushed, true);
            app.GeneratePracticeReportButton.Text = 'Generate Practice Report';
            app.GuitarChordsTab = uitab(app.TabGroup);
            app.GuitarChordsTab.Title = 'Guitar Chords';
            app.GuitarChords_CAGEDLB = uilistbox(app.GuitarChordsTab);
            app.GuitarChords_CAGEDLB.Items = {'C', 'A', 'G', 'E', 'D'};
            app.GuitarChords_CAGEDLB.Value = 'C';
            app.GuitarChords_CO5Axes = uiaxes(app.GuitarChordsTab);
            app.GuitarChords_DisplayChordBtn = uibutton(app.GuitarChordsTab, 'push');
            app.GuitarChords_DisplayChordBtn.ButtonPushedFcn = createCallbackFcn(app, @GuitarChords_DisplayChordBtnButtonPushed, true);
            app.GuitarChords_DisplayChordBtn.Text = 'Display Chord';
            app.KeyDropDownLabel = uilabel(app.GuitarChordsTab);
            app.KeyDropDownLabel.Text = 'Key';
            app.GuitarChords_KeyDD = uidropdown(app.GuitarChordsTab);
            app.GuitarChords_KeyDD.Items = {'A', 'Bb', 'B', 'C', 'Db', 'D', 'Eb', 'E', 'F', 'Gb', 'G', 'Ab'};
            app.GuitarChords_KeyDD.ValueChangedFcn = createCallbackFcn(app, @GuitarChords_KeyDDValueChanged, true);
            app.GuitarChords_KeyDD.Value = 'A';
            app.ChordDropDownLabel = uilabel(app.GuitarChordsTab);
            app.ChordDropDownLabel.Text = 'Chord';
            app.GuitarChords_ChordDD = uidropdown(app.GuitarChordsTab);
            app.GuitarChords_ChordDD.Items = {'I', 'ii', 'iii', 'IV', 'V', 'vi', 'vii'};
            app.GuitarChords_ChordDD.Value = 'I';
            app.SongDatabaseTab = uitab(app.TabGroup);
            app.SongDatabaseTab.Title = 'Song Database';
            app.AddSongButton = uibutton(app.SongDatabaseTab, 'push');
            app.AddSongButton.ButtonPushedFcn = createCallbackFcn(app, @AddSongButtonPushed, true);
            app.AddSongButton.Text = 'Add Song';
            app.UITable = uitable(app.SongDatabaseTab);
            app.UITable.ColumnName = {'Title'; 'Artist/Composer'; 'URL'};
            app.UITable.RowName = {};
            app.DeleteSongButton = uibutton(app.SongDatabaseTab, 'push');
            app.DeleteSongButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSongButtonPushed, true);
            app.DeleteSongButton.Text = 'Delete Song';
            app.SearchButton = uibutton(app.SongDatabaseTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.InstrumentsTab = uitab(app.TabGroup);
            app.InstrumentsTab.Title = 'Instruments';
            app.AddInstrumentButton = uibutton(app.InstrumentsTab, 'push');
            app.AddInstrumentButton.Text = 'Add Instrument';
            app.UITable2 = uitable(app.InstrumentsTab);
            app.UITable2.ColumnName = {'Instrument'; 'Hours Practiced'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.timbrApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timbr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.timbrApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.timbrApp)
        end
    end
end